classdef ex4App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        Menu_2             matlab.ui.container.Menu
        Menu_5             matlab.ui.container.Menu
        Menu_3             matlab.ui.container.Menu
        Menu_4             matlab.ui.container.Menu
        Menu_6             matlab.ui.container.Menu
        Menu_8             matlab.ui.container.Menu
        Menu_9             matlab.ui.container.Menu
        Menu_10            matlab.ui.container.Menu
        Menu_7             matlab.ui.container.Menu
        Menu_11            matlab.ui.container.Menu
        Menu_12            matlab.ui.container.Menu
        Menu_13            matlab.ui.container.Menu
        Menu_15            matlab.ui.container.Menu
        Menu_16            matlab.ui.container.Menu
        Menu_14            matlab.ui.container.Menu
        Menu_17            matlab.ui.container.Menu
        RawImgPanel        matlab.ui.container.Panel
        AngleSpinnerLabel  matlab.ui.control.Label
        AngleSpinner       matlab.ui.control.Spinner
        UIAxes             matlab.ui.control.UIAxes
        ProcessedImgPanel  matlab.ui.container.Panel
        UIAxes2            matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global img % 图像区初始化
            img = imread("init.jpg");
            imshow(img, 'Parent', app.UIAxes);
            imshow(img, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            global filename
            global img
            %Fnameh显示的文件名称，Pnameh显示的文件路径
            [Fnameh,Pnameh]=uigetfile({'*.jpg;*.png;*.jpeg;*.tif','Image (*.jpg,*.jpeg,*.png,*.tif)'});
            filename=[Pnameh,Fnameh]; % 存储文件的路径及名称
            if Fnameh == 0 % 未选择文件
                errordlg('未选择文件','错误');
            else
                img = imread(filename);
                imshow(img, 'Parent', app.UIAxes);
                imshow(img, 'Parent', app.UIAxes2);
            end
            
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            global img
            global imgSave
            imgSave = rgb2gray(img);
            imshow(imgSave, 'Parent', app.UIAxes2);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            global flag
            flag = 1;
            app.AngleSpinner.Visible = true; % Spinner显示
            app.AngleSpinnerLabel.Visible = true;
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            global imgSave
            imwrite(imgSave,'res.jpg') % 保存图像
        end

        % Value changed function: AngleSpinner
        function AngleSpinnerValueChanged(app, event)
            value = app.AngleSpinner.Value;
            global img
            global imgSave
            global flag
            if flag == 1
                tic
                imgSave = imrotate(img, value, "nearest");
                toc
            elseif flag == 2
                tic
                imgSave = imrotate(img, value, "bilinear");
                toc
            elseif flag == 3
                tic
                imgSave = imrotate(img, value, "bicubic");
                toc
            elseif flag == 4
                tic
                imgSave = imresize(img, value);
                toc
            end
            imshow(imgSave, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_9
        function Menu_9Selected(app, event)
            global flag
            flag = 2;
            app.AngleSpinner.Visible = true; % Spinner显示
            app.AngleSpinnerLabel.Visible = true;
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            global flag
            flag = 3;
            app.AngleSpinner.Visible = true; % Spinner显示
            app.AngleSpinnerLabel.Visible = true;
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            global img
            global imgSave
            [imgSave, ~] = imcrop(img); % 调用crop函数
            imshow(imgSave, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            global flag
            flag = 4;
            app.AngleSpinner.Visible = true; % Spinner显示
            app.AngleSpinnerLabel.Visible = true;
            set(app.AngleSpinnerLabel,'text', 'Resize') % resize操作需要对Spinner设定
            set(app.AngleSpinner,'step', 0.1)
            set(app.AngleSpinner,'limits', [0,1])
        end

        % Menu selected function: Menu_15
        function Menu_15Selected(app, event)
            global img
            global imgSave
            tic
            h = size(img);
            f1 = zeros(h(1)+round(h(2)*tan(pi/6)),h(2),h(3),'uint8'); % 水平错切30°
            for m=1:h(1)
                for n=1:h(2)
                    f1(m+round(n*tan(pi/6)),n,1:h(3))=img(m,n,1:h(3));
                end
            end
            toc
            imgSave = f1;
            imshow(imgSave, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_16
        function Menu_16Selected(app, event)
            global img
            global imgSave
            tic
            h = size(img);
            f2 = zeros(h(1),h(2)+round(h(2)*tan(pi/4)),h(3),'uint8'); % 垂直错切30°
            for m=1:h(1)
                for n=1:h(2)
                    f2(m,n+round(m*tan(pi/6)),1:h(3))=img(m,n,1:h(3));
                end
            end
            toc
            imgSave = f2;
            imshow(imgSave, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_14
        function Menu_14Selected(app, event)
            global img
            global imgSave
            tic
            [R, C] = size(img); % 获取图像大小
            res = zeros(R, C, 'uint8'); % 构造结果矩阵。每个像素点默认初始化为0（黑色）
            delX = 100; % 平移量X
            delY = 100; % 平移量Y
            tras = [1 0 delX; 0 1 delY; 0 0 1]; % 平移的变换矩阵
            
            for i = 1 : R
                for j = 1 : C
                    temp = [i; j; 1];
                    temp = tras * temp; % 矩阵乘法
                    x = temp(1, 1);
                    y = temp(2, 1);
                    % 变换后的位置判断是否越界
                    if (x <= R) & (y <= C) & (x >= 1) & (y >= 1)
                        res(x, y) = img(i, j);
                    end
                end
            end
            toc
            imgSave = res;
            imshow(res, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Menu_17
        function Menu_17Selected(app, event)
            global img
            global imgSave
            tic
            [r,c,m] = size(img);
            mirr=255*ones(r,c,m,'uint8');
            for i = 1:r
                for j = 1:c
                    mirr(i,j,:)=img(i,c-j+1,:);
                end
            end
            imgSave = mirr;
            toc
            imshow(mirr, 'Parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '打开';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Separator = 'on';
            app.Menu_5.Text = '保存';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = '操作';
            app.Menu_4 = uimenu(app.Menu_3);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '灰度图转换';
            app.Menu_6 = uimenu(app.Menu_3);
            app.Menu_6.Separator = 'on';
            app.Menu_6.Text = '旋转';
            app.Menu_8 = uimenu(app.Menu_6);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '最邻域旋转';
            app.Menu_9 = uimenu(app.Menu_6);
            app.Menu_9.MenuSelectedFcn = createCallbackFcn(app, @Menu_9Selected, true);
            app.Menu_9.Separator = 'on';
            app.Menu_9.Text = '双线性旋转';
            app.Menu_10 = uimenu(app.Menu_6);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Separator = 'on';
            app.Menu_10.Text = '双立方旋转';
            app.Menu_7 = uimenu(app.Menu_3);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Separator = 'on';
            app.Menu_7.Text = '剪切';
            app.Menu_11 = uimenu(app.Menu_3);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Separator = 'on';
            app.Menu_11.Text = '缩放';
            app.Menu_12 = uimenu(app.Menu_3);
            app.Menu_12.Separator = 'on';
            app.Menu_12.Text = '变换';
            app.Menu_13 = uimenu(app.Menu_12);
            app.Menu_13.Text = '错切';
            app.Menu_15 = uimenu(app.Menu_13);
            app.Menu_15.MenuSelectedFcn = createCallbackFcn(app, @Menu_15Selected, true);
            app.Menu_15.Text = '水平错切';
            app.Menu_16 = uimenu(app.Menu_13);
            app.Menu_16.MenuSelectedFcn = createCallbackFcn(app, @Menu_16Selected, true);
            app.Menu_16.Separator = 'on';
            app.Menu_16.Text = '垂直错切';
            app.Menu_14 = uimenu(app.Menu_12);
            app.Menu_14.MenuSelectedFcn = createCallbackFcn(app, @Menu_14Selected, true);
            app.Menu_14.Separator = 'on';
            app.Menu_14.Text = '平移';
            app.Menu_17 = uimenu(app.Menu_3);
            app.Menu_17.MenuSelectedFcn = createCallbackFcn(app, @Menu_17Selected, true);
            app.Menu_17.Separator = 'on';
            app.Menu_17.Text = '镜像';
            app.RawImgPanel = uipanel(app.UIFigure);
            app.RawImgPanel.Title = 'Raw Img';
            app.UIAxes = uiaxes(app.RawImgPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.AngleSpinnerLabel = uilabel(app.RawImgPanel);
            app.AngleSpinnerLabel.Visible = 'off';
            app.AngleSpinnerLabel.Text = 'Angle';
            app.AngleSpinner = uispinner(app.RawImgPanel);
            app.AngleSpinner.Limits = [-180 180];
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleSpinnerValueChanged, true);
            app.AngleSpinner.Visible = 'off';
            app.ProcessedImgPanel = uipanel(app.UIFigure);
            app.ProcessedImgPanel.Title = 'Processed Img';
            app.UIAxes2 = uiaxes(app.ProcessedImgPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex4App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end